classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeasurementApp               matlab.ui.Figure
        StopButton                   matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        StatusTable                  matlab.ui.control.Table
        Console                      matlab.ui.control.TextArea
        IncrementSizeEditFieldLabel  matlab.ui.control.Label
        IncrementSizeEditField       matlab.ui.control.NumericEditField
        Image                        matlab.ui.control.Image
        IdleLampLabel                matlab.ui.control.Label
        IdleLamp                     matlab.ui.control.Lamp
        PauseButton                  matlab.ui.control.StateButton
    end

    
    properties (Access = public)
    
        consoleObj
        MI4190Obj
        loadBarObj
        wantToStop = false;
    
    end

    methods (Access = public)
        
        function writeConsoleLine(app,lineIn,arg1,arg2,arg3)
            
            if (nargin < 3)
                arg1 = ' ';
                arg2 = ' ';
                arg3 = ' ';
            elseif (nargin < 4)
                arg2 = ' ';
                arg3 = ' ';
            elseif (nargin < 5)
                arg3 = ' ';
            end
            
            if (contains(lineIn,'%s'))
                lineIn = replace(lineIn,'%s',arg1);
            end
            
            if (contains(lineIn,'%.2f'))
                lineIn = replace(lineIn,'%.2f',sprintf('%.2f',arg2)); 
            end
            
            if (contains(lineIn,'%.2d'))
                lineIn = replace(lineIn,'%.2d',sprintf('%.2f',arg3)); 
            end
            
            if (strcmp(lineIn,'\n'))
                app.Console.Value = [' ';app.Console.Value];
                return;
            end
            
            app.Console.Value = [replace(lineIn,'\n',' ');app.Console.Value];
            
            if (contains(lineIn,'\n'))
                app.Console.Value = [' ';app.Console.Value];
                return;
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.consoleObj.line1 = 'Console Started';
            app.Console.Value = {app.consoleObj.line1};
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            writeConsoleLine(app,'Start Button pressed');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            MeasurementAppCloseRequest(app);
        end

        % Close request function: MeasurementApp
        function MeasurementAppCloseRequest(app, event)
            app.writeConsoleLine('[%s] Stopping Measurements. . .',datestr(now,'HH:MM:SS.FFF'));
            fprintf('[%s] Stopping Measurements. . .\n',datestr(now,'HH:MM:SS.FFF'))
            
            app.wantToStop = true;
            delete(app.loadBarObj);
            fclose(app.MI4190Obj);
            delete(app.MI4190Obj);
            %delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeasurementApp = uifigure('Visible', 'off');
            app.MeasurementApp.Name = 'Measurement App';
            app.MeasurementApp.CloseRequestFcn = createCallbackFcn(app, @MeasurementAppCloseRequest, true);
            app.StopButton = uibutton(app.MeasurementApp, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.MeasurementApp, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StatusTable = uitable(app.MeasurementApp);
            app.StatusTable.ColumnName = {'Value'};
            app.StatusTable.RowName = {'Current Position: '; 'Next Position: '; 'Current Velocity: '; 'Angles Remaining: '};
            app.Console = uitextarea(app.MeasurementApp);
            app.Console.Editable = 'off';
            app.IncrementSizeEditFieldLabel = uilabel(app.MeasurementApp);
            app.IncrementSizeEditFieldLabel.Text = 'Increment Size';
            app.IncrementSizeEditField = uieditfield(app.MeasurementApp, 'numeric');
            app.IncrementSizeEditField.Limits = [0 180];
            app.PauseButton = uibutton(app.MeasurementApp, 'state');
            app.PauseButton.Text = 'Pause';
            app.Image = uiimage(app.MeasurementApp);
            app.Image.ImageSource = 'Screenshot from 2020-01-17 16-13-31.png';
            app.IdleLampLabel = uilabel(app.MeasurementApp);
            app.IdleLampLabel.Text = 'Idle';
            app.IdleLamp = uilamp(app.MeasurementApp);
            % Show the figure after all components are created
            app.MeasurementApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeasurementApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeasurementApp)
        end
    end
end